classdef AppLauncher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EarthButton                  matlab.ui.control.StateButton
        ParticleTrancingInplanetaryMagnetospheresLabel  matlab.ui.control.Label
        NumberofBouncemotionperiodsdisplayedSpinnerLabel  matlab.ui.control.Label
        NumberofBouncemotionperiodsdisplayedSpinner  matlab.ui.control.Spinner
        EnergyoftheprotonMevSpinnerLabel  matlab.ui.control.Label
        EnergyoftheprotonMevSpinner  matlab.ui.control.Spinner
        PitchAngleArcTanvperpenvparallelKnobLabel  matlab.ui.control.Label
        PitchAngleArcTanvperpenvparallelKnob  matlab.ui.control.Knob
        GraphicsDisplayButtonGroup   matlab.ui.container.ButtonGroup
        DAnimationButton             matlab.ui.control.StateButton
        DComparisonButton            matlab.ui.control.StateButton
        LatitudeComparisonButton     matlab.ui.control.StateButton
        EnergyComparisonButton       matlab.ui.control.StateButton
        JupiterButton                matlab.ui.control.Button
        InstructionsetTextAreaLabel  matlab.ui.control.Label
        InstructionsetTextArea       matlab.ui.control.TextArea
        AuthorshipButton             matlab.ui.control.Button
        SaturnButton                 matlab.ui.control.Button
    end


    methods (Access = private)

        % Value changed function: EarthButton
        function EarthButtonValueChanged(app, event)
            
            %%%%%Preliminaries
            % solver to use
            solver = 'ode15s';
            % ODE solver options
            options = odeset('AbsTol',1e-12,'RelTol',1e-12,'Stats','off','vectorized','on');
            Re = 6378e3; % Earth equatorial radius in m
            
            % Earth dipole field
            B0 = 3.07e-5;        % Magnetic equator field strength in T
            Md = [0,0,-B0*Re^3]; % Magnetic moment
            Rm = [0,0,0];        % Centered moment
            
            % SI constants
            mp = 1.6726231e-27;  % proton mass 
            qp = 1.60217733e-19; % proton charge
            eV = 1.60217733e-19; % conversion factor eV to J
            c  = 299792458.0;    % speed of light
            
            Ep = app.EnergyoftheprotonMevSpinner.Value*10^6*eV;       % 10 MeV proton
            vp = sqrt(2/mp*Ep); % classic velocity 
            fprintf('classic v=%.4g m/s E=%.4g MeV\n',vp,.5*mp*vp^2/(1e6*eV))
            vp = c*sqrt(Ep*(Ep+2*mp*c^2))/(Ep+mp*c^2); % relativistic velocity
            fprintf('relativ v=%.4g m/s E=%.4g MeV\n',vp,(1/sqrt(1-vp^2/c^2)-1)*mp*c^2/(1e6*eV));
            V2 = vp^2;
            
            % relativistic factor
            gamma = 1/sqrt(1-V2/c^2);
            
            % proton charge-to-mass ratio
            qOverM = qp/mp;
            
            % pitch angle deg
            alpha = app.PitchAngleArcTanvperpenvparallelKnob.Value;
            timespec = app.NumberofBouncemotionperiodsdisplayedSpinner.Value;
            close all
            % initial conditions
            [X,Xgc,mu,Tc,Tb,Td,Lm] = init(4*Re,vp,alpha);
            % factor mu/gamma^2/m for guiding centre Eq.23 of Ozturk 2012
            facdv = mu/gamma^2/mp; 
            tspan = [0,timespec*Tb];
            fprintf(1,'tmax=%.2f (%.1f tc, %.2f tb, %.2f td)\n',...
        tspan(end)./[1,Tc,Tb,Td]);
            trace(X,Xgc);
            
            function [Xo,Xogc,mu,tc,tb,td,lm]=init(R,v,alpha)
            
            % initial condition x0,y0,z0,vx0,vy0,vz0
            Xo = [R;            0;            0;...
      0;v*sind(alpha);v*cosd(alpha)];
            
            % initial guiding centre x0,y0,z0,vpar0
            B = dipoleMagneticField3D(Md,Rm,{Xo(1),Xo(2),Xo(3)});
            b = sqrt(B{4});
            % smaller vpar gives larger vper thus guiding centre motion
            %vpar = 0.9220*sum(Xo(4:6).*[B{1};B{2};B{3}]/b);
            vpar = sum(Xo(4:6).*[B{1};B{2};B{3}]/b);
            vper = sqrt(V2-vpar^2);
            % gyro radius
            rho = abs(gamma/qOverM*vper/b);
            % add or substract gyroradius depending on sign of q and magnetic moment
            Xogc = [R-sign(qOverM*Md(3))*rho;0;0;vpar];
            B = dipoleMagneticField3D(Md,Rm,{Xogc(1),Xogc(2),Xogc(3)});
            b = sqrt(B{4});
            % first invariant mu
            mu = gamma^2*mp*vper^2/(2*b);
            [tc,tb,td] = periods(R);
            lm = mirrorlat(alpha);
            L = R/Re;
            % https://farside.ph.utexas.edu/teaching/plasma/lectures1/node22.html
            alphaloss = asind(sqrt(1/sqrt(4*L^6-3*L^5)));
            fprintf(1,'|alpha| %.2f |pi-alpha| %.2f loss cone %.2f deg\n', ...
        abs(alpha),abs(180-alpha),alphaloss);
            if abs(alpha)<alphaloss || abs(180-alpha)<alphaloss,
              fprintf(1,'warning: particle in loss cone!\n');
            end
            	
            end
            
            function trace(Xo,Xogc)
            
            solverh = fcnchk(solver);
            
            % Planet's surface
            ts = linspace(0,2*pi,100);
            Xp = cos(ts);
            Yp = sin(ts);
            
            
            [tfd,Xfd] = solverh(@dynamic3d,tspan,Xo,options); 
            
            Zfd    = Xfd(:,3);
            Rcylfd = sqrt(Xfd(:,1).^2+Xfd(:,2).^2);
            Rtotfd = sqrt(Xfd(:,1).^2+Xfd(:,2).^2+Xfd(:,3).^2);
            
            [tgc,Xgc] = solverh(@gcdynamic3d,tspan,Xogc,options);
            
            Zgc    = Xgc(:,3);
            Rcylgc = sqrt(Xgc(:,1).^2+Xgc(:,2).^2);
            Rtotgc = sqrt(Xgc(:,1).^2+Xgc(:,2).^2+Xgc(:,3).^2);
            
            
            rm = {Xo(1)*cosd(Lm)^3,Xo(1)*cosd(Lm)^2*sind(Lm),0};
            B = dipoleMagneticField3D(Md,Rm,rm);
            tm = 2*pi/(qOverM/gamma*sqrt(B{4}));
            tb = linspace(tspan(1),tspan(end),4*fix(diff(tspan)/tm))';
            hb = diff(tb);
            fprintf(1,'tm=%.2f, tc/tm=%.2f, tc/hb(1)=%.2f\n', [tm,fix(Tc/tm),fix(Tc/hb(1))]);
            Xb = zeros(length(tb),length(Xo));
            Xb(1,:) = BorisInit(Xo,hb(1));
            
            
            %%%%
            %3D ANIMATION OF FIELD LINES
            if app.DAnimationButton.Value
            pos = Xb(1, 1:3);
            posfd = Xfd(1 ,1:3); 
            posgc = Xgc(1, 1:3);
            figure
            view(60,24)
            
            [x,y,z]= sphere; 
            surf(x*Re, y*Re,z*Re);
            hold on
            animation =animatedline('Color','b');
            animation2 =animatedline('Color','y');
            animation3 =animatedline('Color','r');
            animation2iterator = size (Xgc);
            animation3iterator = size (Xfd);
            
            for i = 1:animation2iterator(1)-1
    posgc = Xgc(i+1, 1:3);
    addpoints(animation2, posgc(1), posgc(2), posgc(3));
    drawnow limitrate
            end
            for i = 1:animation3iterator(1)-1
    posfd = Xfd(i+1, 1:3);
    addpoints(animation3, posfd(1), posfd(2), posfd(3));
    drawnow limitrate 
            end
            for i=1:length(tb)-1
              Xb(i+1,:) = BorisIter(Xb(i,:),hb(i));
              pos = Xb(i+1, 1:3);
              addpoints(animation, pos(1), pos(2), pos(3))
              drawnow
              
            end
            legend('Earth','Particle Trace Boris','Guiding Centre','Particle Trace Full Dynamic')
            title('Proton trapped in Eath s magnetic dipole')
            pause 
            %%%%%
            Zb    = Xb(:,3);
            Rcylb = sqrt(Xb(:,1).^2+Xb(:,2).^2);
            Rtotb = sqrt(Xb(:,1).^2+Xb(:,2).^2+Xb(:,3).^2);
            end 
            
            
            %%%%
            %PLOTTING 
            if app.DComparisonButton.Value
            figure
            plot(Rcylfd/Re,Zfd/Re,Rcylb/Re,Zb/Re,Rcylgc/Re,Zgc/Re,Xp,Yp), 
            axis equal,
            xlabel('rcyl'), ylabel('z')
            legend({'FD','FDB','GC'})
            fprintf(1,'Ok, press return\n'), 
            pause
            fprintf(1,'\n');
            end 
            % save all trajectories
            save cmptraj_earth tfd Xfd tb Xb tgc Xgc timespec Tc Tb Td Lm
            
            % Kinetic energy in eV for Full Dynamic
            v2fd = sum(Xfd(:,[4:6])'.^2);
            %E = 1/2*mp*v2fd/eV;  % classic
            Efd = (1./sqrt(1-v2fd/c^2)-1)*mp*c^2/eV; % relativistic
            % mean and variance
            meanEfd = mean(Efd);
            stdEfd = std(Efd);
            fprintf(1,'<Efd> = %10g MeV, std(E) = %10g eV\n', meanEfd/1e6, stdEfd);
            
            % Kinetic energy in eV for Full Dynamic Boris
            v2b = sum(Xb(:,[4:6])'.^2);
            %Eb = 1/2*mp*v2b/eV;  % classic
            Eb = (1./sqrt(1-v2b/c^2)-1)*mp*c^2/eV; % relativistic
            % mean and variance
            meanEb = mean(Eb);
            stdEb = std(Eb);
            fprintf(1,'<Eb>  = %10g MeV, std(E) = %10g eV\n', meanEb/1e6, stdEb);
            
             % Compute b dot gradB and mu for Full Dynamic
            [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xfd(:,1),Xfd(:,2),Xfd(:,3)});
            BgBfd = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2fd = V2-(B{1}.*Xfd(:,4)+B{2}.*Xfd(:,5)+B{3}.*Xfd(:,6)).^2./B{4};
            % Instantaneous first invariant Eq.14 
            muifd = gamma^2*mp*Vper2fd./(2*sqrt(B{4}));
            
            % Compute b dot gradB and mu for Full Dynamic Boris
            [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xb(:,1),Xb(:,2),Xb(:,3)});
            BgBb = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2b = V2-(B{1}.*Xb(:,4)+B{2}.*Xb(:,5)+B{3}.*Xb(:,6)).^2./B{4};
            % Instantaneous first invariant Eq.14
            muib = gamma^2*mp*Vper2b./(2*sqrt(B{4}));
            
            % Compute b dot gradB and mu for Guiding Centre
            [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xgc(:,1),Xgc(:,2),Xgc(:,3)});
            BgBgc = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2gc = V2-Xgc(:,4).^2;
            % Instantaneous first invariant Eq.14 
            muigc = gamma^2*mp*Vper2gc./(2*sqrt(B{4}));
            
            
            %%%%%%%%%
            if app.EnergyComparisonButton.Value
                
            subplot(211),
            plot(tfd,Efd-meanEfd,tb,Eb-meanEb);
            xlabel('time'), ylabel('E-<E>')
            legend({'FD','FDB'})
             
            subplot(212), 
            plot(tfd,muifd,tb,muib,tgc,muigc,tgc,facdv*gamma^2*mp*ones(size(tgc))),
            ylabel('\mu 1st invariant')
            legend('FD','FDB','GC','Initial')
            
            pause
            fprintf(1,'\n');
            end 
            %%%%%%%%%


            % Compute latitude and longitude
            latfd = atan2d(Zfd,Rcylfd);        % atan(Rcyl/Z)
            lonfd = atan2d(Xfd(:,2),Xfd(:,1)); % atan(X/Y);
            latb = atan2d(Zb,Rcylb);        % atan(Rcyl/Z)
            lonb = atan2d(Xb(:,2),Xb(:,1)); % atan(X/Y);
            latgc = atan2d(Zgc,Rcylgc);
            longc = atan2d(Xgc(:,2),Xgc(:,1));
            
            
            % L-Shell with L
            t = linspace(-pi/2,pi/2,100);
            % estimate L from instantaneous L
            %coslat = Rcyl./R; Ls = R./coslat.^2/Re;
            Lsfd = Rtotfd.^3./Rcylfd.^2/Re;
            Lsgc = Rtotgc.^3./Rcylgc.^2/Re;
            Le = mean(Lsfd);
            fprintf(1,'L estimated = %.2f\n', Le);
            r = Le *cos(t).^2;
            ir = find(r>=1);
            xe = r(ir).*cos(t(ir));
            ye = r(ir).*sin(t(ir));
            
            %%%%%%%%%
            % plot trajectory Rcyl vs Z
            if app.DComparisonButton.Value
            subplot(211), 
            plot(Rcylfd/Re,Zfd/Re,Rcylb/Re,Zb/Re,Rcylgc/Re,Zgc/Re,xe,ye,'-.',Xp,Yp), 
            xlabel('$r_{cyl}=\sqrt{x^2+y^2}$','interpreter','latex'),
            ylabel('$z$','interpreter','latex'),
            title(sprintf('%s x0=%.0f x0gc=%.4f L=%.4f', ...
      solver, Xo(1)/Re, Xogc(1)/Re,Le),...
      'interpreter','latex')
            legend({'FD','FDB','GC','L-shell'},'interpreter','latex');
            %set(gca,'xlim',[0 4.5])
            %set(gca,'ylim',[-4.5 4.5])
            axis equal,
            subplot(212),
            if 1,
            % plot trajectory X vs Y
            plot(Xfd(:,1)/Re,Xfd(:,2)/Re,...
     Xb(:,1)/Re,Xb(:,2)/Re,...
     Xgc(:,1)/Re,Xgc(:,2)/Re,Xp,Yp), 
            xlabel('$x$','interpreter','latex'),
            ylabel('$y$','interpreter','latex'),
            else
            % 3d plot trajectory
            plot3(Xfd(:,1)/Re,Xfd(:,2)/Re,Xfd(:,3)/Re,...
      Xb(:,1)/Re,Xb(:,2)/Re,Xb(:,3)/Re,...
      Xgc(:,1)/Re,Xgc(:,2)/Re,Xgc(:,3)/Re), 
            xlabel('$x$','interpreter','latex'),
            ylabel('$y$','interpreter','latex'),
            zlabel('$z$','interpreter','latex'),
            end
            title(sprintf('$\\langle{E}\\rangle=$%2g MeV std$(E)=$%2g eV',...
      meanEfd/1e6,stdEfd),...
      'interpreter','latex');
            %set(gca,'xlim',[-4.5 4.5])
            %set(gca,'ylim',[-4.5 4.5])
            axis equal
            
            fprintf(1,'Ok, press return\n'),
            pause
            fprintf(1,'\n');
            end
            %%%%%%%%%


            %%%%%%%
            if app.LatitudeComparisonButton.Value
            subplot(211), 
            plot(tfd,latfd,tb,latb,tgc,latgc), xlabel('time'); ylabel('Latitude')
            legend('FD','FDB','GC')
            %%%%%%%
            
            [tbfd,dtbfd] = getbounceperiod(tfd,latfd,2*pi/Tb);
            [tbb,dtbb] = getbounceperiod(tb,latb,2*pi/Tb);
            [tbgc,dtbgc] = getbounceperiod(tgc,latgc,2*pi/Tb);
            
            %%%%%%
            subplot(212), 
            plot(tfd,lonfd,tb,lonb,tgc,longc), xlabel('time'); ylabel('Longitude')
            legend('FD','FDB','GC')
            %%%%%%
            
            [tdfd,dtdfd] = getdriftperiod(tfd,lonfd,2*pi/tbfd,360);
            [tdb,dtdb] = getdriftperiod(tb,lonb,2*pi/tbb,360);
            [tdgc,dtdgc] = getdriftperiod(tgc,longc,2*pi/tbgc,360);
            
            drawnow
            end 
            end
            
            function rn = BorisIter(r,h)
            
            rn = zeros(size(r));
            
            [x,y,z,vx,vy,vz] = deal(r(1),r(2),r(3),r(4),r(5),r(6));
            
            B = dipoleMagneticField3D(Md, Rm, {x,y,z});
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            
            %T = -tan(B{4}*h/2.0)*[Bx,By,Bz]'/B{4};
            T = fac*[Bx,By,Bz]'*h/2;
            S = 2.0 * T /(1+T'*T);
            
            V = [vx,vy,vz]';
            R = [x,y,z]';
            
            v = V + cross(V,T);
            V = V + cross(v,S);
            
            R = R + V*h;
            
            rn = [R;V];
            
            end
            
            function r0 = BorisInit(r,h)
            
            r0 = zeros(size(r));
            
            [x,y,z,vx,vy,vz] = deal(r(1),r(2),r(3),r(4),r(5),r(6));
            
            B = dipoleMagneticField3D(Md, Rm, {x,y,z});
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            
            T = fac*[Bx,By,Bz]'*h/2;
            
            
            V = [vx,vy,vz]';
            R = [x,y,z]';
            
            V = V - cross(V,T);
            
            V = V * vp/sqrt(sum(V.^2));
            
            r0 = [R;V];
            
            end
            
            % 3D ODE function dr/dt=f(r) where r=(x,y,z,vx,vy,vz)
            function dr_dt = dynamic3d(t,r)
            
            %fprintf(1,'t=%.2g\n', t)
            
            [x,y,z,vx,vy,vz] = deal(r(1,:),r(2,:),r(3,:),r(4,:),r(5,:),r(6,:));
            
            B = dipoleMagneticField3D(Md, Rm, {x,y,z});
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            dvx = fac*(vy.*Bz - vz.*By);
            dvy = fac*(vz.*Bx - vx.*Bz);
            dvz = fac*(vx.*By - vy.*Bx);
            
            dr_dt = [vx;vy;vz;dvx;dvy;dvz];
            
            end
            
            function dr_dt = gcdynamic3d(t,r)
            
            [x,y,z,vpar] = deal(r(1,:),r(2,:),r(3,:),r(4,:));
            
            [B,gradB] = dipoleMagneticField3D(Md, Rm, {x,y,z});
            [Bx,By,Bz,B2] = deal(B{:});
            [gradBx,gradBy,gradBz] = deal(gradB{:});
            
            B = B2.^.5;
            iB = 1./B;
            bx = Bx.*iB; by = By.*iB; bz = Bz.*iB;
            
            fac = gamma./(2*qOverM*B2).*(V2 + vpar.^2);
            dx = fac.*(by.*gradBz - bz.*gradBy) + vpar.*bx;
            dy = fac.*(bz.*gradBx - bx.*gradBz) + vpar.*by;
            dz = fac.*(bx.*gradBy - by.*gradBx) + vpar.*bz;
            %dv = -(V2 - vpar.^2)./(2*B).*(bx.*gradBx+by.*gradBy+bz.*gradBz);
            dv = -facdv*(bx.*gradBx+by.*gradBy+bz.*gradBz);
            
            dr_dt = [dx;dy;dz;dv];
            
            end
            
            function [tc,tb,td] = periods(R)
            
            B = dipoleMagneticField3D(Md, Rm, {R,0,0});
            Be = dipoleMagneticField3D(Md, Rm, {Re,0,0});
            
            B  = sqrt(B{4});
            Be = sqrt(Be{4});
            
            % Angular gyro frequency
            Omegac = qOverM/gamma*B;
            
            % Gyro period
            tc = 2*pi/Omegac;
            
            % Bouncing period
            % factor 0.117 = Re/c*sqrt(2)*3.7
            tb = 0.117*(R/Re)*(c/vp)*(1-0.4635*sind(alpha)^0.75);
            % https://farside.ph.utexas.edu/teaching/plasma/lectures1/node22.html
            %tb = R*sqrt(2)/vp*(3.7-1.6*sind(alpha));
            
            % Drift period 
            td = 2*pi*qOverM*Be*Re^3/vp^2/R*(1-1/3*(sind(alpha))^0.62);
            % https://farside.ph.utexas.edu/teaching/plasma/lectures1/node23.html
            %td = pi*qp*Be*Re^3/(3*Ep*R)/(0.35+0.15*sind(alpha));
            %td = 1.05/(Ep/(1e6*eV))/(R/Re)/(1+0.43*sind(alpha))*3600;
            
            fprintf(1,'tc=%6.2f s, tb=%6.2f s, td=%6.2f s\n', tc,tb,td);
            
            end
            
            function lm = mirrorlat(alpha)
            
            % numerically 
            opts = optimset('fzero');
            myfun = @(x,a) cosd(x).^6-sind(a)^2.*sqrt(1+3*sind(x).^2);
            lm = fzero(@(x) myfun(x,alpha),45,opts);
            
            fprintf(1,'pitcheq=%.2f lm=%.2f deg\n',alpha,lm);
            
            end


        end

        % Value changed function: PitchAngleArcTanvperpenvparallelKnob
        function angle(app, event)
            value = app.PitchAngleArcTanvperpenvparallelKnob.Value;
            
        end

        % Button pushed function: AuthorshipButton
        function authors(app, event)
            myicon = imread('logo.png');
            h=msgbox('Parameterization of Ozturks 2012 paper 2012 Trajectories of charged particles trapped in Earths magnetic field Copyright (c) 2009-2016 Patrick Guio <patrick.guio@gmail.com> All Rights Reserved This program is free software; you can redistribute it and/or modify it under the terms of the GNU General Public License as published by the Free Software Foundation; either version 2.  of the License, or (at your option) any later version. This program is distributed in the hope that it will be useful, but WITHOUT ANY WARRANTY; without even the implied warranty of MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU General Public License for more details. You should have received a copy of the GNU General Public License along with this program. If not see http://www.gnu.org/licenses/ ','Authorship','custom',myicon);
            
        end

        % Button pushed function: JupiterButton
        function camptraj_Jupiter(app, event)
            
            % solver to use
            solver = 'ode15s';
          
            % ODE solver options
            options = odeset('AbsTol',1e-12,'RelTol',1e-12,'Stats','off','vectorized','on');
       
            
            % close all figures
            close all
            
            % mdisc flag
            mdisc = true;
            
            % Jupiter magnetodisc
            global MDFile
            MDFile = 'jup_mdisc_kh3e7_rmp90.mat';
            % clear function with persistent variables
            clear MDiscField
            load(MDFile,'MD')
            
            % Jupiter equatorial radius in m
            %Re = 71492e3; 
            Re = MD.planet.r0;
            
            
            B0 = MD.planet.B0 * MD.v2d.Bthdip(MD.dims.imu0,1);
            Md = [0,0,B0*Re^3];  % Magnetic moment
            Rm = [0,0,0];        % Centered moment
            
            
            % SI constants
            mp = 1.6726231e-27;  % proton mass 
            qp = 1.60217733e-19; % proton charge
            eV = 1.60217733e-19; % conversion factor eV to J
            c  = 299792458.0;    % speed of light
            
            Ep = app.EnergyoftheprotonMevSpinner.Value*10^6*eV;       % MeV proton
            vp = sqrt(2/mp*Ep);  % classic velocity 
            fprintf('classic v=%.4g m/s E=%.4g MeV\n',vp,.5*mp*vp^2/(1e6*eV))
            vp = c*sqrt(Ep*(Ep+2*mp*c^2))/(Ep+mp*c^2); % relativistic velocity
            fprintf('relativ v=%.4g m/s E=%.4g MeV\n',vp,(1/sqrt(1-vp^2/c^2)-1)*mp*c^2/(1e6*eV));
            % velocity squared for GC solution
            V2 = vp^2;
            
            % relativistic factor
            gamma = 1/sqrt(1-V2/c^2);
            
            % proton charge-to-mass ratio
            qOverM = qp/mp;
            
            % pitch angle deg
            alpha = app.PitchAngleArcTanvperpenvparallelKnob.Value;
            %alpha = 15;
            %alpha = 4;
            
            close all
            % initial conditions
            [X,Xgc,mu,Tc,Tb,Td,Lm] = init(4*Re,vp,alpha);
            % factor mu/gamma^2/m for guiding centre Eq.23 of Ozturk 2012
            facdv = mu/gamma^2/mp; 
            timespec = app.NumberofBouncemotionperiodsdisplayedSpinner.Value;
            tspan = [0,timespec*Tb];
            fprintf(1,'tmax=%.2f (%.1f tc, %.2f tb, %.2f td)\n',...
        tspan(end)./[1,Tc,Tb,Td]);
            trace(X,Xgc);
            
            function [Xo,Xogc,mu,tc,tb,td,lm]=init(R,v,alpha)
            
            % initial condition x0,y0,z0,vx0,vy0,vz0
            Xo = [R;            0;            0;...
      0;v*sind(alpha);v*cosd(alpha)];
            
            % initial guiding centre x0,y0,z0,vpar0
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{Xo(1)/Re,Xo(2)/Re,Xo(3)/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{Xo(1),Xo(2),Xo(3)});
            end
            b = sqrt(B{4});
            % smaller vpar gives larger vper thus guiding centre motion
       
            vpar = sum(Xo(4:6).*[B{1};B{2};B{3}]/b);
            vper = sqrt(V2-vpar^2);
            % gyro radius
            rho = abs(gamma/qOverM*vper/b);
            % add or substract gyroradius depending on sign of q and magnetic moment
            Xogc = [R-sign(qOverM*Md(3))*rho;0;0;vpar];
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{Xogc(1)/Re,Xogc(2)/Re,Xogc(3)/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{Xogc(1),Xogc(2),Xogc(3)});
            end
            b = sqrt(B{4});
            % first invariant mu
            mu = gamma^2*mp*vper^2/(2*b);
            [tc,tb,td] = periods(R);
            lm = mirrorlat(alpha);
            L = R/Re;
            % https://farside.ph.utexas.edu/teaching/plasma/lectures1/node22.html
            alphaloss = asind(sqrt(1/sqrt(4*L^6-3*L^5)));
            fprintf(1,'|alpha| %.2f |pi-alpha| %.2f loss cone %.2f deg\n', ...
        abs(alpha),abs(180-alpha),alphaloss);
            if abs(alpha)<alphaloss || abs(180-alpha)<alphaloss,
              fprintf(1,'warning: particle in loss cone!\n');
            end
            	
            end
            
            function trace(Xo,Xogc)
            
            solverh = fcnchk(solver);
            
            % Planet's surface
            ts = linspace(0,2*pi,100);
            Xp = cos(ts);
            Yp = sin(ts);
            
            
            [tfd,Xfd] = solverh(@dynamic3d,tspan,Xo,options); 
            Zfd    = Xfd(:,3);
            Rcylfd = sqrt(Xfd(:,1).^2+Xfd(:,2).^2);
            Rtotfd = sqrt(Xfd(:,1).^2+Xfd(:,2).^2+Xfd(:,3).^2);
            
            [tgc,Xgc] = solverh(@gcdynamic3d,tspan,Xogc,options);
            Zgc    = Xgc(:,3);
            Rcylgc = sqrt(Xgc(:,1).^2+Xgc(:,2).^2);
            Rtotgc = sqrt(Xgc(:,1).^2+Xgc(:,2).^2+Xgc(:,3).^2);
            
            rm = {Xo(1)*cosd(Lm)^3,Xo(1)*cosd(Lm)^2*sind(Lm),0};
            if mdisc,
              B = mdiscMagneticField3D(Rm,{rm{1}/Re,rm{2}/Re,rm{3}/Re});
            else,
              B = dipoleMagneticField3D(Md,Rm,rm);
            end
            % Gyro period at mirror point
            tm = 2*pi/(qOverM/gamma*sqrt(B{4}));
            %tb = linspace(0,t(end),floor(t(end)/max(diff(t))));
            tb = linspace(tspan(1),tspan(end),6*fix(diff(tspan)/tm))';
            %tb = t;
            hb = diff(tb);
            fprintf(1,'tm=%.2f, tc/tm=%.2f, tc/hb(1)=%.2f\n',[tm,fix(Tc/tm),fix(Tc/hb(1))]);
            Xb = zeros(length(tb),length(Xo));
            Xb(1,:) = BorisInit(Xo,hb(1));
            
            %%%%%
            %3D ANIMATION 
            if app.DAnimationButton.Value
            pos = Xb(1, 1:3);
            posfd = Xfd(1 ,1:3); 
            posgc = Xgc(1, 1:3);
            figure
            view(60,24)
            
            [x,y,z]= sphere; 
            surf(x*Re, y*Re,z*Re);
            hold on
            animation =animatedline('Color','b');
            animation2 =animatedline('Color','y');
            animation3 =animatedline('Color','r');
            animation2iterator = size (Xgc);
            animation3iterator = size (Xfd);
            for i = 1:animation2iterator(1)-1
            posgc = Xgc(i+1, 1:3);
            addpoints(animation2, posgc(1), posgc(2), posgc(3));
            drawnow limitrate
            end
            for i = 1:animation3iterator(1)-1
            posfd = Xfd(i+1, 1:3);
            addpoints(animation3, posfd(1), posfd(2), posfd(3));
            drawnow limitrate 
            end
            for i=1:length(tb)-1
              Xb(i+1,:) = BorisIter(Xb(i,:),hb(i));
              pos = Xb(i+1, 1:3);
              addpoints(animation, pos(1), pos(2), pos(3))
              drawnow limitrate
              
            end
            legend('Jupiter','Particle Trace Boris','Guiding Centre','Particle Trace Full Dynamic')
            title('Proton trapped in Jupiter magnetic dipole')
            pause 
            end
            %%%%%%
            Zb    = Xb(:,3);
            Rcylb = sqrt(Xb(:,1).^2+Xb(:,2).^2);
            Rtotb = sqrt(Xb(:,1).^2+Xb(:,2).^2+Xb(:,3).^2);
            %%%%%
            
            %%%%%
            %PLOTTING
            if app.DComparisonButton.Value
            figure
            plot(Rcylfd/Re,Zfd/Re,Rcylb/Re,Zb/Re,Rcylgc/Re,Zgc/Re,Xp,Yp),
            axis equal,
            xlabel('rcyl'), ylabel('z')
            legend({'FD','FDB','GC'})
            fprintf(1,'Ok, press return\n'),
            pause
            fprintf(1,'\n');
            end
            
            
            % save all trajectories
            save cmptraj_jupiter tfd Xfd tb Xb tgc Xgc timespec Tc Tb Td Lm
            
            % Kinetic energy in eV for Full Dynamic
            v2fd = sum(Xfd(:,[4:6])'.^2);
            %Efd = 1/2*mp*v2fd/eV;  % classic
            Efd = (1./sqrt(1-v2fd/c^2)-1)*mp*c^2/eV; % relativistic
            % mean and variance
            meanEfd = mean(Efd);
            stdEfd = std(Efd);
            fprintf(1,'<E>  = %10g MeV, std(E) = %10g eV\n', meanEfd/1e6, stdEfd);
            
            % Kinetic energy in eV for Full Dynamic Boris
            v2b = sum(Xb(:,[4:6])'.^2);
            %Eb = 1/2*mp*v2b/eV;  % classic
            Eb = (1./sqrt(1-v2b/c^2)-1)*mp*c^2/eV; % relativistic
            % mean and variance
            meanEb = mean(Eb);
            stdEb = std(Eb);
            fprintf(1,'<Eb> = %10g MeV, std(E) = %10g eV\n', meanEb/1e6, stdEb);


            % Compute b dot gradB and mu for Full Dynamic
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{Xfd(:,1)/Re,Xfd(:,2)/Re,Xfd(:,3)/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xfd(:,1),Xfd(:,2),Xfd(:,3)});
            end
            BgBfd = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2fd = V2-(B{1}.*Xfd(:,4)+B{2}.*Xfd(:,5)+B{3}.*Xfd(:,6)).^2./B{4};
            % Instantaneous first invariant Eq.14 
            muifd = gamma^2*mp*Vper2fd./(2*sqrt(B{4}));
            
            % Compute b dot gradB and mu for Full Dynamic Boris
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{Xb(:,1)/Re,Xb(:,2)/Re,Xb(:,3)/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xb(:,1),Xb(:,2),Xb(:,3)});
            end
            BgBb = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2b = V2-(B{1}.*Xb(:,4)+B{2}.*Xb(:,5)+B{3}.*Xb(:,6)).^2./B{4};
            % Instantaneous first invariant Eq.14 
            muib = gamma^2*mp*Vper2b./(2*sqrt(B{4}));
            
            % Compute b dot gradB and mu for Guiding Centre
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{Xgc(:,1)/Re,Xgc(:,2)/Re,Xgc(:,3)/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xgc(:,1),Xgc(:,2),Xgc(:,3)});
            end
            BgBgc = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2gc = V2-Xgc(:,4).^2;
            % Instantaneous first invariant Eq.14 
            muigc = gamma^2*mp*Vper2gc./(2*sqrt(B{4}));
            
            %%%%%%%
            if app.EnergyComparisonButton.Value
            subplot(211)
            plot(tfd,Efd-meanEfd,tb,Eb-meanEb);
            xlabel('time'), ylabel('E-<E>')
            legend({'FD','FDB'})
            fprintf(1,'Ok, press return\n'),
            
            
            subplot(212), 
            plot(tfd,muifd,tb,muib,tgc,muigc,tgc,facdv*gamma^2*mp*ones(size(tgc))),
            ylabel('\mu 1st invariant')
            legend('FD','FDB','GC','Initial')
            
            pause
            fprintf(1,'\n');
            end 
            %%%%%%%
            
            % Compute latitude and longitude
            latfd = atan2d(Zfd,Rcylfd);        % atan(Rcyl/Z)
            lonfd = atan2d(Xfd(:,2),Xfd(:,1)); % atan(X/Y);
            latb = atan2d(Zb,Rcylb);        % atan(Rcyl/Z)
            lonb = atan2d(Xb(:,2),Xb(:,1)); % atan(X/Y);
            latgc = atan2d(Zgc,Rcylgc);
            longc = atan2d(Xgc(:,2),Xgc(:,1));
            
            
            % L-Shell with L
            t = linspace(-pi/2,pi/2,100);
            % estimate L from instantaneous L
            %coslat = Rcyl./R; Ls = R./coslat.^2/Re;
            Lsfd = Rtotfd.^3./Rcylfd.^2/Re;
            Lsgc = Rtotgc.^3./Rcylgc.^2/Re;
            Le = mean(Lsfd);
            fprintf(1,'L estimated = %.2f\n', Le);
            
            r = Le *cos(t).^2;
            ir = find(r>=1);
            xe = r(ir).*cos(t(ir));
            ye = r(ir).*sin(t(ir));
            
            %%%%%
            % plot trajectory Rcyl vs Z
            if app.DComparisonButton.Value
            subplot(211), 
            plot(Rcylfd/Re,Zfd/Re,Rcylb/Re,Zb/Re,Rcylgc/Re,Zgc/Re,xe,ye,'-.',Xp,Yp), 
            xlabel('$r_{cyl}=\sqrt{x^2+y^2}$','interpreter','latex'),
            ylabel('$z$','interpreter','latex'),
            title(sprintf('%s x0=%.0f x0gc=%.4f L=%.4f', ...
      solver, Xo(1)/Re, Xogc(1)/Re,Le),...
      'interpreter','latex')
            legend({'FD','FDB','GC','L-shell'},'interpreter','latex');
            %set(gca,'xlim',[0 4.5])
            %set(gca,'ylim',[-4.5 4.5])
            axis equal,
            
            subplot(212),
            if 1,
            % plot trajectory X vs Y
            plot(Xfd(:,1)/Re,Xfd(:,2)/Re,...
     Xb(:,1)/Re,Xb(:,2)/Re,...
     Xgc(:,1)/Re,Xgc(:,2)/Re,Xp,Yp), 
            xlabel('$x$','interpreter','latex'),
            ylabel('$y$','interpreter','latex'),
            else
            % 3d plot trajectory
            plot3(Xfd(:,1)/Re,Xfd(:,2)/Re,Xfd(:,3)/Re,...
      Xb(:,1)/Re,Xb(:,2)/Re,Xb(:,3)/Re,...
      Xgc(:,1)/Re,Xgc(:,2)/Re,Xgc(:,3)/Re), 
            xlabel('$x$','interpreter','latex'),
            ylabel('$y$','interpreter','latex'),
            zlabel('$z$','interpreter','latex'),
            end
            title(sprintf('$\\langle{E}\\rangle=$%2g MeV std$(E)=$%2g eV',...
      meanEfd/1e6,stdEfd),...
      'interpreter','latex');
            %set(gca,'xlim',[-4.5 4.5])
            %set(gca,'ylim',[-4.5 4.5])
            axis equal
            
            fprintf(1,'Ok, press return\n'),
            pause
            fprintf(1,'\n');
            end
            %%%%%
            
            %%%%%
            if app.LatitudeComparisonButton.Value
            subplot(211),
            plot(tfd,latfd,tb,latb,tgc,latgc), xlabel('time'); ylabel('Latitude')
            legend('FD','FDB','GC')
            
            [tbfd,dtbfd] = getbounceperiod(tfd,latfd,2*pi/Tb);
            [tbb,dtbb] = getbounceperiod(tb,latb,2*pi/Tb);
            [tbgc,dtbgc] = getbounceperiod(tgc,latgc,2*pi/Tb);
            
            subplot(212),
            plot(tfd,lonfd,tb,lonb,tgc,longc), xlabel('time'); ylabel('Longitude')
            legend('FD','FDB','GC')
            
            
            [tdfd,dtdfd] = getdriftperiod(tfd,lonfd,2*pi/tbfd,360);
            [tdb,dtdb] = getdriftperiod(tb,lonb,2*pi/tbb,360);
            [tdgc,dtdgc] = getdriftperiod(tgc,longc,2*pi/tbgc,360);
            
            drawnow
            end
            %%%%%
            
            end 
            function rn = BorisIter(r,h)
            
            rn = zeros(size(r));
            
            [x,y,z,vx,vy,vz] = deal(r(1),r(2),r(3),r(4),r(5),r(6));
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{x,y,z});
            end
            
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            
            %T = -tan(B{4}*h/2.0)*[Bx,By,Bz]'/B{4};
            T = fac*[Bx,By,Bz]'*h/2;
            S = 2.0 * T /(1+T'*T);
            
            V = [vx,vy,vz]';
            R = [x,y,z]';
            
            v = V + cross(V,T);
            V = V + cross(v,S);
            
            R = R + V*h;
            
            rn = [R;V];
            
            end
            
            function r0 = BorisInit(r,h)
            
            r0 = zeros(size(r));
            
            [x,y,z,vx,vy,vz] = deal(r(1),r(2),r(3),r(4),r(5),r(6));
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{x,y,z});
            end
            
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            
            T = fac*[Bx,By,Bz]'*h/2;
            
            
            V = [vx,vy,vz]';
            R = [x,y,z]';
            
            V = V - cross(V,T);
            
            % don't forget to keep v constant
            V = V * vp/sqrt(sum(V.^2));
            
            r0 = [R;V];
            
            end
            
            % 3D ODE function dr/dt=f(r) where r=(x,y,z,vx,vy,vz)
            function dr_dt = dynamic3d(t,r)
            
            %fprintf(1,'t=%.2g\n', t)
            
            [x,y,z,vx,vy,vz] = deal(r(1,:),r(2,:),r(3,:),r(4,:),r(5,:),r(6,:));
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{x,y,z});
            end
            
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            dvx = fac*(vy.*Bz - vz.*By);
            dvy = fac*(vz.*Bx - vx.*Bz);
            dvz = fac*(vx.*By - vy.*Bx);
            
            dr_dt = [vx;vy;vz;dvx;dvy;dvz];
            
            end
            
            function dr_dt = gcdynamic3d(t,r)
            
            [x,y,z,vpar] = deal(r(1,:),r(2,:),r(3,:),r(4,:));
            
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {x,y,z});
            end
            [Bx,By,Bz,B2] = deal(B{:});
            [gradBx,gradBy,gradBz] = deal(gradB{:});
            
            B = B2.^.5;
            iB = 1./B;
            bx = Bx.*iB; by = By.*iB; bz = Bz.*iB;
            
            fac = gamma./(2*qOverM*B2).*(V2 + vpar.^2);
            dx = fac.*(by.*gradBz - bz.*gradBy) + vpar.*bx;
            dy = fac.*(bz.*gradBx - bx.*gradBz) + vpar.*by;
            dz = fac.*(bx.*gradBy - by.*gradBx) + vpar.*bz;
            %dv = -(V2 - vpar.^2)./(2*B).*(bx.*gradBx+by.*gradBy+bz.*gradBz);
            dv = -facdv*(bx.*gradBx+by.*gradBy+bz.*gradBz);
            
            dr_dt = [dx;dy;dz;dv];
            
            end
            
            function [tc,tb,td] = periods(R)
            
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm, {R/Re,0,0});
              Be = mdiscMagneticField3D(Rm, {Re/Re,0,0});
            else, % dipole field
              B = dipoleMagneticField3D(Md, Rm, {R,0,0});
              Be = dipoleMagneticField3D(Md, Rm, {Re,0,0});
            end
            
            B  = sqrt(B{4});
            Be = sqrt(Be{4});
            
            % Angular gyro frequency
            Omegac = qOverM/gamma*B;
            
            % Gyro period
            tc = 2*pi/Omegac;
            
            % Bouncing period
            tb = 1.2478*(R/Re)*c/vp*(1-0.4635*sind(alpha)^0.75);
            % Drift period 
            td = 2*pi*qOverM*Be*Re^3/vp^2/R*(1-1/3*(sind(alpha))^0.62)
            fprintf(1,'tc=%6.2f s, tb=%6.2f s, td=%6.2f s\n', tc,tb,td);
            
            end
            
            function lm = mirrorlat(alpha)          
            % numerically 
            opts = optimset('fzero');
            myfun = @(x,a) cosd(x).^6-sind(a)^2.*sqrt(1+3*sind(x).^2);
            lm = fzero(@(x) myfun(x,alpha),45,opts);
            
            fprintf(1,'pitcheq=%.2f lm=%.2f deg\n',alpha,lm);
            
            end


        end

        % Button pushed function: SaturnButton
        function camptraj_Saturn(app, event)
             
            
            % solver to use   
            solver = 'ode15s';
        
            % ODE solver options
            options = odeset('AbsTol',1e-12,'RelTol',1e-12,'Stats','off','vectorized','on');
            
            % close all figures
            close all
            
            % mdisc flag
            mdisc = true;
            
            % Saturn magnetodisc
            global MDFile
            MDFile = 'sat_mdisc_kh2e6_rmp25.mat';
            % clear function with persistent variables
            clear MDiscField
            load(MDFile,'MD');
            
            % Saturn equatorial radius in m
            %Re = 60280e3; 
            Re = MD.planet.r0;
            
            % Saturn dipole field
            B0 = MD.planet.B0 * MD.v2d.Bthdip(MD.dims.imu0,1);
            Md = [0,0,B0*Re^3];  % Magnetic moment
            Rm = [0,0,0];        % Centered moment
            
            
            % SI constants
            mp = 1.6726231e-27;  % proton mass 
            qp = 1.60217733e-19; % proton charge
            eV = 1.60217733e-19; % conversion factor eV to J
            c  = 299792458.0;    % speed of light
            
            Ep = app.EnergyoftheprotonMevSpinner.Value*10^6*eV;       % 100 MeV proton
            vp = sqrt(2/mp*Ep);  % classic velocity 
            fprintf('classic v=%.4g m/s E=%.4g MeV\n',vp,.5*mp*vp^2/(1e6*eV))
            vp = c*sqrt(Ep*(Ep+2*mp*c^2))/(Ep+mp*c^2); % relativistic velocity
            fprintf('relativ v=%.4g m/s E=%.4g MeV\n',vp,(1/sqrt(1-vp^2/c^2)-1)*mp*c^2/(1e6*eV));
            % velocity squared for GC solution
            V2 = vp^2;
            
            % relativistic factor
            gamma = 1/sqrt(1-V2/c^2);
            
            % proton charge-to-mass ratio
            qOverM = qp/mp;
            
            % pitch angle deg
            alpha = app.PitchAngleArcTanvperpenvparallelKnob.Value;
            
            
            close all
            % initial conditions
            [X,Xgc,mu,Tc,Tb,Td,Lm] = init(4*Re,vp,alpha);
            % factor mu/gamma^2/m for guiding centre Eq.23 of Ozturk 2012
            facdv = mu/gamma^2/mp; 
            timespec = app.NumberofBouncemotionperiodsdisplayedSpinner.Value;
            tspan = [0,timespec*Tb];
            fprintf(1,'tmax=%.2f (%.1f tc, %.2f tb, %.2f td)\n',...
        tspan(end)./[1,Tc,Tb,Td]);
            trace(X,Xgc);
            
            function [Xo,Xogc,mu,tc,tb,td,lm]=init(R,v,alpha)
            
            % initial condition x0,y0,z0,vx0,vy0,vz0
            Xo = [R;            0;            0;...
      0;v*sind(alpha);v*cosd(alpha)];
            
            % initial guiding centre x0,y0,z0,vpar0
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{Xo(1)/Re,Xo(2)/Re,Xo(3)/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{Xo(1),Xo(2),Xo(3)});
            end
            b = sqrt(B{4});
            % smaller vpar gives larger vper thus guiding centre motion
            %vpar = 0.9220*sum(Xo(4:6).*[B{1};B{2};B{3}]/b);
            vpar = sum(Xo(4:6).*[B{1};B{2};B{3}]/b);
            vper = sqrt(V2-vpar^2);
            % gyro radius
            rho = abs(gamma/qOverM*vper/b);
            % add or substract gyroradius depending on sign of q and magnetic moment
            Xogc = [R-sign(qOverM*Md(3))*rho;0;0;vpar];
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{Xogc(1)/Re,Xogc(2)/Re,Xogc(3)/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{Xogc(1),Xogc(2),Xogc(3)});
            end
            b = sqrt(B{4});
            % first invariant mu
            mu = gamma^2*mp*vper^2/(2*b);
            [tc,tb,td] = periods(R);
            lm = mirrorlat(alpha);
            L = R/Re;
            % https://farside.ph.utexas.edu/teaching/plasma/lectures1/node22.html
            alphaloss = asind(sqrt(1/sqrt(4*L^6-3*L^5)));
            fprintf(1,'|alpha| %.2f |pi-alpha| %.2f loss cone %.2f deg\n', ...
        abs(alpha),abs(180-alpha),alphaloss);
            if abs(alpha)<alphaloss || abs(180-alpha)<alphaloss,
              fprintf(1,'warning: particle in loss cone!\n');
            end
            	
            end
            
            function trace(Xo,Xogc)
            
            solverh = fcnchk(solver);
            
            % Planet's surface
            ts = linspace(0,2*pi,100);
            Xp = cos(ts);
            Yp = sin(ts);
            
            
            [tfd,Xfd] = solverh(@dynamic3d,tspan,Xo,options); 
            Zfd    = Xfd(:,3);
            Rcylfd = sqrt(Xfd(:,1).^2+Xfd(:,2).^2);
            Rtotfd = sqrt(Xfd(:,1).^2+Xfd(:,2).^2+Xfd(:,3).^2);
            
            
            [tgc,Xgc] = solverh(@gcdynamic3d,tspan,Xogc,options);
            Zgc    = Xgc(:,3);
            Rcylgc = sqrt(Xgc(:,1).^2+Xgc(:,2).^2);
            Rtotgc = sqrt(Xgc(:,1).^2+Xgc(:,2).^2+Xgc(:,3).^2);
            rm = {Xo(1)*cosd(Lm)^3,Xo(1)*cosd(Lm)^2*sind(Lm),0};
            
            if mdisc,
              B = mdiscMagneticField3D(Rm,{rm{1}/Re,rm{2}/Re,rm{3}/Re});
            else,
              B = dipoleMagneticField3D(Md,Rm,rm);
            end
            % Gyro period at mirror point
            tm = 2*pi/(qOverM/gamma*sqrt(B{4}));
            %tb = linspace(0,t(end),floor(t(end)/max(diff(t))));
            tb = linspace(tspan(1),tspan(end),6*fix(diff(tspan)/tm))';
            %tb = t;
            hb = diff(tb);
            fprintf(1,'tm=%.2f, tc/tm=%.2f, tc/hb(1)=%.2f\n',[tm,fix(Tc/tm),fix(Tc/hb(1))]);
            Xb = zeros(length(tb),length(Xo));
            Xb(1,:) = BorisInit(Xo,hb(1));
            
            
            %%%%
            %3D ANIMATION OF FIELD LINES
            if app.DAnimationButton.Value
            pos = Xb(1, 1:3);
            posfd = Xfd(1 ,1:3); 
            posgc = Xgc(1, 1:3);
            figure
            view(60,24)
            axis equal
            
            [x,y,z]= sphere; 
            surf(x*Re, y*Re,z*Re);
            hold on
            animation =animatedline('Color','b');
            animation2 =animatedline('Color','y');
            animation3 =animatedline('Color','r');
            animation2iterator = size (Xgc);
            animation3iterator = size (Xfd);
            
            for i = 1:animation2iterator(1)-1
    posgc = Xgc(i+1, 1:3);
    addpoints(animation2, posgc(1), posgc(2), posgc(3));
    drawnow limitrate
            end
            for i = 1:animation3iterator(1)-1
    posfd = Xfd(i+1, 1:3);
    addpoints(animation3, posfd(1), posfd(2), posfd(3));
    drawnow limitrate 
            end
            for i=1:length(tb)-1
              Xb(i+1,:) = BorisIter(Xb(i,:),hb(i));
              pos = Xb(i+1, 1:3);
              addpoints(animation, pos(1), pos(2), pos(3))
              drawnow
              
            end
            legend('Saturn','Particle Trace Boris','Guiding Centre','Particle Trace Full Dynamic')
            title('Proton trapped in Saturn s magnetic dipole')
            pause 
            end
            %%%%%
            
            Zb    = Xb(:,3);
            Rcylb = sqrt(Xb(:,1).^2+Xb(:,2).^2);
            Rtotb = sqrt(Xb(:,1).^2+Xb(:,2).^2+Xb(:,3).^2);
            
            
            %%%%
            if app.DComparisonButton.Value
            figure
            plot(Rcylfd/Re,Zfd/Re,Rcylb/Re,Zb/Re,Rcylgc/Re,Zgc/Re,Xp,Yp),
            axis equal,
            xlabel('rcyl'), ylabel('z')
            legend({'FD','FDB','GC'})
            fprintf(1,'Ok, press return\n'),
            pause
            fprintf(1,'\n');
            end
            %%%%%
            
            % save all trajectories
            save cmptraj_saturn tfd Xfd tb Xb tgc Xgc timespec Tc Tb Td Lm
            
            % Kinetic energy in eV for Full Dynamic
            v2fd = sum(Xfd(:,[4:6])'.^2);
            %Efd = 1/2*mp*v2fd/eV;  % classic
            Efd = (1./sqrt(1-v2fd/c^2)-1)*mp*c^2/eV; % relativistic
            % mean and variance
            meanEfd = mean(Efd);
            stdEfd = std(Efd);
            fprintf(1,'<E>  = %10g MeV, std(E) = %10g eV\n', meanEfd/1e6, stdEfd);
            
            % Kinetic energy in eV for Full Dynamic Boris
            v2b = sum(Xb(:,[4:6])'.^2);
            %Eb = 1/2*mp*v2b/eV;  % classic
            Eb = (1./sqrt(1-v2b/c^2)-1)*mp*c^2/eV; % relativistic
            % mean and variance
            meanEb = mean(Eb);
            stdEb = std(Eb);
            fprintf(1,'<Eb> = %10g MeV, std(E) = %10g eV\n', meanEb/1e6, stdEb);
            
            % Compute b dot gradB and mu for Full Dynamic
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{Xfd(:,1)/Re,Xfd(:,2)/Re,Xfd(:,3)/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xfd(:,1),Xfd(:,2),Xfd(:,3)});
            end
            BgBfd = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2fd = V2-(B{1}.*Xfd(:,4)+B{2}.*Xfd(:,5)+B{3}.*Xfd(:,6)).^2./B{4};
            % Instantaneous first invariant Eq.14 
            muifd = gamma^2*mp*Vper2fd./(2*sqrt(B{4}));
            
            % Compute b dot gradB and mu for Full Dynamic Boris
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{Xb(:,1)/Re,Xb(:,2)/Re,Xb(:,3)/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xb(:,1),Xb(:,2),Xb(:,3)});
            end
            BgBb = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2b = V2-(B{1}.*Xb(:,4)+B{2}.*Xb(:,5)+B{3}.*Xb(:,6)).^2./B{4};
            % Instantaneous first invariant Eq.14 
            muib = gamma^2*mp*Vper2b./(2*sqrt(B{4}));
            
            % Compute b dot gradB and mu for Guiding Centre
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{Xgc(:,1)/Re,Xgc(:,2)/Re,Xgc(:,3)/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {Xgc(:,1),Xgc(:,2),Xgc(:,3)});
            end
            BgBgc = (B{1}.*gradB{1}+B{2}.*gradB{2}+B{3}.*gradB{3})./sqrt(B{4});
            Vper2gc = V2-Xgc(:,4).^2;
            % Instantaneous first invariant Eq.14 
            muigc = gamma^2*mp*Vper2gc./(2*sqrt(B{4}));
            
            %%%%%
            if app.EnergyComparisonButton.Value
            subplot(211)
            plot(tfd,Efd-meanEfd,tb,Eb-meanEb);
            xlabel('time'), ylabel('E-<E>')
            legend({'FD','FDB'})
            fprintf(1,'Ok, press return\n'),
            
            subplot(212), 
            plot(tfd,muifd,tb,muib,tgc,muigc,tgc,facdv*gamma^2*mp*ones(size(tgc))),
            ylabel('\mu 1st invariant')
            legend('FD','FDB','GC','Initial')
            
            pause
            fprintf(1,'\n');
            end
            %%%%%


            % Compute latitude and longitude
            latfd = atan2d(Zfd,Rcylfd);        % atan(Rcyl/Z)
            lonfd = atan2d(Xfd(:,2),Xfd(:,1)); % atan(X/Y);
            latb = atan2d(Zb,Rcylb);        % atan(Rcyl/Z)
            lonb = atan2d(Xb(:,2),Xb(:,1)); % atan(X/Y);
            latgc = atan2d(Zgc,Rcylgc);
            longc = atan2d(Xgc(:,2),Xgc(:,1));
            
            
            % L-Shell with L
            t = linspace(-pi/2,pi/2,100);
            % estimate L from instantaneous L
            %coslat = Rcyl./R; Ls = R./coslat.^2/Re;
            Lsfd = Rtotfd.^3./Rcylfd.^2/Re;
            Lsgc = Rtotgc.^3./Rcylgc.^2/Re;
            Le = mean(Lsfd);
            fprintf(1,'L estimated = %.2f\n', Le);
            if 0,
            	f=figure; 
            	plot(tfd,Lsfd), 
            	pause, close(f);
            end
            r = Le *cos(t).^2;
            ir = find(r>=1);
            xe = r(ir).*cos(t(ir));
            ye = r(ir).*sin(t(ir));
            
            %%%%
            % plot trajectory Rcyl vs Z
            if app.DComparisonButton.Value
            subplot(211), 
            plot(Rcylfd/Re,Zfd/Re,Rcylb/Re,Zb/Re,Rcylgc/Re,Zgc/Re,xe,ye,'-.',Xp,Yp), 
            xlabel('$r_{cyl}=\sqrt{x^2+y^2}$','interpreter','latex'),
            ylabel('$z$','interpreter','latex'),
            title(sprintf('%s x0=%.0f x0gc=%.4f L=%.4f', ...
      solver, Xo(1)/Re, Xogc(1)/Re,Le),...
      'interpreter','latex')
            legend({'FD','FDB','GC','L-shell'},'interpreter','latex');
            %set(gca,'xlim',[0 4.5])
            %set(gca,'ylim',[-4.5 4.5])
            axis equal,
            
            subplot(212),
            if 1,
            % plot trajectory X vs Y
            plot(Xfd(:,1)/Re,Xfd(:,2)/Re,...
     Xb(:,1)/Re,Xb(:,2)/Re,...
     Xgc(:,1)/Re,Xgc(:,2)/Re,Xp,Yp), 
            xlabel('$x$','interpreter','latex'),
            ylabel('$y$','interpreter','latex'),
            else
            % 3d plot trajectory
            plot3(Xfd(:,1)/Re,Xfd(:,2)/Re,Xfd(:,3)/Re,...
      Xb(:,1)/Re,Xb(:,2)/Re,Xb(:,3)/Re,...
      Xgc(:,1)/Re,Xgc(:,2)/Re,Xgc(:,3)/Re), 
            xlabel('$x$','interpreter','latex'),
            ylabel('$y$','interpreter','latex'),
            zlabel('$z$','interpreter','latex'),
            end
            title(sprintf('$\\langle{E}\\rangle=$%2g MeV std$(E)=$%2g eV',...
      meanEfd/1e6,stdEfd),...
      'interpreter','latex');
            %set(gca,'xlim',[-4.5 4.5])
            %set(gca,'ylim',[-4.5 4.5])
            axis equal
            
            fprintf(1,'Ok, press return\n'),
            pause
            fprintf(1,'\n');
            end 
            %%%%%%
            
            %%%%%%
            if app.LatitudeComparisonButton.Value
            subplot(211),
            plot(tfd,latfd,tb,latb,tgc,latgc), xlabel('time'); ylabel('Latitude')
            legend('FD','FDB','GC')
            
            [tbfd,dtbfd] = getbounceperiod(tfd,latfd,2*pi/Tb);
            [tbb,dtbb] = getbounceperiod(tb,latb,2*pi/Tb);
            [tbgc,dtbgc] = getbounceperiod(tgc,latgc,2*pi/Tb);
            
            subplot(212),
            plot(tfd,lonfd,tb,lonb,tgc,longc), xlabel('time'); ylabel('Longitude')
            legend('FD','FDB','GC')
            
            
            [tdfd,dtdfd] = getdriftperiod(tfd,lonfd,2*pi/tbfd,360);
            [tdb,dtdb] = getdriftperiod(tb,lonb,2*pi/tbb,360);
            [tdgc,dtdgc] = getdriftperiod(tgc,longc,2*pi/tbgc,360);
            
            drawnow
            end
            %%%%%%%%%
            
            end
            
            %%%%%%
            %AUXIALIARY FUNCTIONS
            function rn = BorisIter(r,h)
            
            rn = zeros(size(r));
            
            [x,y,z,vx,vy,vz] = deal(r(1),r(2),r(3),r(4),r(5),r(6));
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{x,y,z});
            end
            
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            
            %T = -tan(B{4}*h/2.0)*[Bx,By,Bz]'/B{4};
            T = fac*[Bx,By,Bz]'*h/2;
            S = 2.0 * T /(1+T'*T);
            
            V = [vx,vy,vz]';
            R = [x,y,z]';
            
            v = V + cross(V,T);
            V = V + cross(v,S);
            
            R = R + V*h;
            
            rn = [R;V];
            
            end
            
            function r0 = BorisInit(r,h)
            
            r0 = zeros(size(r));
            
            [x,y,z,vx,vy,vz] = deal(r(1),r(2),r(3),r(4),r(5),r(6));
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{x,y,z});
            end
            
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            
            T = fac*[Bx,By,Bz]'*h/2;
            
            
            V = [vx,vy,vz]';
            R = [x,y,z]';
            
            V = V - cross(V,T);
            
            % don't forget to keep v constant
            V = V * vp/sqrt(sum(V.^2));
            
            r0 = [R;V];
            
            end
            
            % 3D ODE function dr/dt=f(r) where r=(x,y,z,vx,vy,vz)
            function dr_dt = dynamic3d(t,r)
            
            %fprintf(1,'t=%.2g\n', t)
            
            [x,y,z,vx,vy,vz] = deal(r(1,:),r(2,:),r(3,:),r(4,:),r(5,:),r(6,:));
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else, % dipole field
              B = dipoleMagneticField3D(Md,Rm,{x,y,z});
            end
            
            [Bx,By,Bz] = deal(B{1:3});
            
            fac = qOverM/gamma;
            dvx = fac*(vy.*Bz - vz.*By);
            dvy = fac*(vz.*Bx - vx.*Bz);
            dvz = fac*(vx.*By - vy.*Bx);
            
            dr_dt = [vx;vy;vz;dvx;dvy;dvz];
            
            end
            
            function dr_dt = gcdynamic3d(t,r)
            
            [x,y,z,vpar] = deal(r(1,:),r(2,:),r(3,:),r(4,:));
            
            if mdisc,
              [B,gradB] = mdiscMagneticField3D(Rm,{x/Re,y/Re,z/Re});
            else
              [B,gradB] = dipoleMagneticField3D(Md, Rm, {x,y,z});
            end
            [Bx,By,Bz,B2] = deal(B{:});
            [gradBx,gradBy,gradBz] = deal(gradB{:});
            
            B = B2.^.5;
            iB = 1./B;
            bx = Bx.*iB; by = By.*iB; bz = Bz.*iB;
            
            fac = gamma./(2*qOverM*B2).*(V2 + vpar.^2);
            dx = fac.*(by.*gradBz - bz.*gradBy) + vpar.*bx;
            dy = fac.*(bz.*gradBx - bx.*gradBz) + vpar.*by;
            dz = fac.*(bx.*gradBy - by.*gradBx) + vpar.*bz;
            %dv = -(V2 - vpar.^2)./(2*B).*(bx.*gradBx+by.*gradBy+bz.*gradBz);
            dv = -facdv*(bx.*gradBx+by.*gradBy+bz.*gradBz);
            
            dr_dt = [dx;dy;dz;dv];
            
            end
            
            function [tc,tb,td] = periods(R)
            
            
            if mdisc, % mdisc field
              B = mdiscMagneticField3D(Rm, {R/Re,0,0});
              Be = mdiscMagneticField3D(Rm, {Re/Re,0,0});
            else, % dipole field
              B = dipoleMagneticField3D(Md, Rm, {R,0,0});
              Be = dipoleMagneticField3D(Md, Rm, {Re,0,0});
            end
            
            B  = sqrt(B{4});
            Be = sqrt(Be{4});
            
            % Angular gyro frequency
            Omegac = qOverM/gamma*B;
            
            % Gyro period
            tc = 2*pi/Omegac;
            
            % Bouncing period
            % factor 1.0521 =  Re/c*sqrt(2)*3.7
            tb = 1.0521*(R/Re)*c/vp*(1-0.4635*sind(alpha)^0.75);
            % https://farside.ph.utexas.edu/teaching/plasma/lectures1/node22.html
            %tb = R*sqrt(2)/vp*(3.7-1.6*sind(alpha));
            
            % Drift period 
            td = 2*pi*qOverM*Be*Re^3/vp^2/R*(1-1/3*(sind(alpha))^0.62);
            % https://farside.ph.utexas.edu/teaching/plasma/lectures1/node23.html
            %td = pi*qp*Be*Re^3/(3*Ep*R)/(0.35+0.15*sind(alpha));
            %td = 1.05/(Ep/(1e6*eV))/(R/Re)/(1+0.43*sind(alpha))*3600;
            
            fprintf(1,'tc=%6.2f s, tb=%6.2f s, td=%6.2f s\n', tc,tb,td);
            
            end
            
            function lm = mirrorlat(alpha)


            % numerically 
            opts = optimset('fzero');
            myfun = @(x,a) cosd(x).^6-sind(a)^2.*sqrt(1+3*sind(x).^2);
            lm = fzero(@(x) myfun(x,alpha),45,opts);
            
            fprintf(1,'pitcheq=%.2f lm=%.2f deg\n',alpha,lm);
            
            end


        end

        % Value changed function: DComparisonButton
        function display2d(app, event)
            disp2 = app.DComparisonButton.Value;
            
            
        end

        % Value changed function: DAnimationButton
        function display3d(app, event)
            disp3 = app.DAnimationButton.Value;
            
        end

        % Value changed function: EnergyComparisonButton
        function displaye(app, event)
            dispe = app.EnergyComparisonButton.Value;
            
        end

        % Value changed function: LatitudeComparisonButton
        function displayl(app, event)
            displ = app.LatitudeComparisonButton.Value;
            
        end

        % Value changed function: EnergyoftheprotonMevSpinner
        function enerfy(app, event)
            value = app.EnergyoftheprotonMevSpinner.Value;
            
        end

        % Value changed function: 
        % NumberofBouncemotionperiodsdisplayedSpinner
        function time(app, event)
            value = app.NumberofBouncemotionperiodsdisplayedSpinner.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EarthButton = uibutton(app.UIFigure, 'state');
            app.EarthButton.ValueChangedFcn = createCallbackFcn(app, @EarthButtonValueChanged, true);
            app.EarthButton.Text = 'Earth';
            app.ParticleTrancingInplanetaryMagnetospheresLabel = uilabel(app.UIFigure);
            app.ParticleTrancingInplanetaryMagnetospheresLabel.Text = {'Particle Trancing In planetary '; 'Magnetospheres'};
            app.NumberofBouncemotionperiodsdisplayedSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofBouncemotionperiodsdisplayedSpinnerLabel.Text = 'Number of Bounce motion periods displayed ';
            app.NumberofBouncemotionperiodsdisplayedSpinner = uispinner(app.UIFigure);
            app.NumberofBouncemotionperiodsdisplayedSpinner.ValueChangedFcn = createCallbackFcn(app, @time, true);
            app.NumberofBouncemotionperiodsdisplayedSpinner.Limits = [1 5];
            app.NumberofBouncemotionperiodsdisplayedSpinner.Value = 1;
            app.EnergyoftheprotonMevSpinnerLabel = uilabel(app.UIFigure);
            app.EnergyoftheprotonMevSpinnerLabel.Text = 'Energy of the proton (M ev)';
            app.EnergyoftheprotonMevSpinner = uispinner(app.UIFigure);
            app.EnergyoftheprotonMevSpinner.Step = 10;
            app.EnergyoftheprotonMevSpinner.ValueChangedFcn = createCallbackFcn(app, @enerfy, true);
            app.EnergyoftheprotonMevSpinner.Limits = [0 Inf];
            app.EnergyoftheprotonMevSpinner.Value = 10;
            app.PitchAngleArcTanvperpenvparallelKnobLabel = uilabel(app.UIFigure);
            app.PitchAngleArcTanvperpenvparallelKnobLabel.Text = 'Pitch Angle (ArcTan [vperpen/vparallel])';
            app.PitchAngleArcTanvperpenvparallelKnob = uiknob(app.UIFigure, 'continuous');
            app.PitchAngleArcTanvperpenvparallelKnob.Limits = [0 90];
            app.PitchAngleArcTanvperpenvparallelKnob.ValueChangedFcn = createCallbackFcn(app, @angle, true);
            app.GraphicsDisplayButtonGroup = uibuttongroup(app.UIFigure);
            app.GraphicsDisplayButtonGroup.Title = 'Graphics Display ';
            app.DAnimationButton = uibutton(app.GraphicsDisplayButtonGroup, 'state');
            app.DAnimationButton.ValueChangedFcn = createCallbackFcn(app, @display3d, true);
            app.DAnimationButton.Text = '3D-Animation ';
            app.DComparisonButton = uibutton(app.GraphicsDisplayButtonGroup, 'state');
            app.DComparisonButton.ValueChangedFcn = createCallbackFcn(app, @display2d, true);
            app.DComparisonButton.Text = '2D-Comparison';
            app.LatitudeComparisonButton = uibutton(app.GraphicsDisplayButtonGroup, 'state');
            app.LatitudeComparisonButton.ValueChangedFcn = createCallbackFcn(app, @displayl, true);
            app.LatitudeComparisonButton.Text = 'Latitude Comparison';
            app.EnergyComparisonButton = uibutton(app.GraphicsDisplayButtonGroup, 'state');
            app.EnergyComparisonButton.ValueChangedFcn = createCallbackFcn(app, @displaye, true);
            app.EnergyComparisonButton.Text = 'Energy Comparison';
            app.JupiterButton = uibutton(app.UIFigure, 'push');
            app.JupiterButton.ButtonPushedFcn = createCallbackFcn(app, @camptraj_Jupiter, true);
            app.JupiterButton.Text = 'Jupiter';
            app.InstructionsetTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsetTextAreaLabel.Text = 'Instruction set ';
            app.InstructionsetTextArea = uitextarea(app.UIFigure);
            app.InstructionsetTextArea.Editable = 'off';
            app.InstructionsetTextArea.Value = {'Welcome! To launch  the simulation first select the desired parameters for the initial conditions using the gadgets at the upper part of the interface.  Then, select the graphic display options. Finally, press one of the planet buttons to activate the simulation in the chosen planet. '};
            app.AuthorshipButton = uibutton(app.UIFigure, 'push');
            app.AuthorshipButton.ButtonPushedFcn = createCallbackFcn(app, @authors, true);
            app.AuthorshipButton.Text = 'Authorship';
            app.SaturnButton = uibutton(app.UIFigure, 'push');
            app.SaturnButton.ButtonPushedFcn = createCallbackFcn(app, @camptraj_Saturn, true);
            app.SaturnButton.Text = 'Saturn';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppLauncher

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end