classdef tracing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EnergySliderLabel      matlab.ui.control.Label
        EnergySlider           matlab.ui.control.Slider
        maxtimeSliderLabel     matlab.ui.control.Label
        maxtimeSlider          matlab.ui.control.Slider
        PeriodSliderLabel      matlab.ui.control.Label
        PeriodSlider           matlab.ui.control.Slider
        ReadyButton            matlab.ui.control.StateButton
        ParticleTrancingLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Value changed function: EnergySlider
        function Energy(app, event)
            Euser = app.EnergySlider.Value;           
        end

        % Value changed function: PeriodSlider
        function Period(app, event)
            Tuser = app.PeriodSlider.Value;     
        end

        % Value changed function: ReadyButton
        function ReadyButtonValueChanged(app, event)
            close all 
            tmaxuser = app.maxtimeSlider.Value;
            Tuser = app.PeriodSlider.Value;
            Euser = app.EnergySlider.Value;  
            answer = ['the energy of the particle is', Euser, 'and the period of the its motion', Tuser]; 
            
            %exporting values from slider
            q = 1;
            m = 1;
            B = [0 0 1]*((m*Tuser)/q); 
            v = [1 1 1]; %initial conditions
            speed = sqrt(2*Euser);
            dt = .1;
            pos = [ 0 0 0] ;
            t = 0 ;  
            h = animatedline;
            view(43,24);
            while t < tmaxuser 
                t = t + dt ;
                a = cross(v, B);
                v = v + a*dt; 
                v = (v/norm(v))*speed ;%imposing the condition that no acc can be exerted 
                pos = pos + v*dt;
                disp(v)
                addpoints (h, pos(1), pos(2), pos(3));
                drawnow %limitrate %%%this should stop it but oh well... 
                
            end       
        end

        % Value changed function: maxtimeSlider
        function maxtime(app, event)
            tmaxuser = app.maxtimeSlider.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnergySliderLabel = uilabel(app.UIFigure);
            app.EnergySliderLabel.Text = 'Energy';
            app.EnergySlider = uislider(app.UIFigure);
            app.EnergySlider.Limits = [0 20];
            app.EnergySlider.ValueChangedFcn = createCallbackFcn(app, @Energy, true);
            app.maxtimeSliderLabel = uilabel(app.UIFigure);
            app.maxtimeSliderLabel.Text = 'max time';
            app.maxtimeSlider = uislider(app.UIFigure);
            app.maxtimeSlider.Limits = [0 20];
            app.maxtimeSlider.ValueChangedFcn = createCallbackFcn(app, @maxtime, true);
            app.PeriodSliderLabel = uilabel(app.UIFigure);
            app.PeriodSliderLabel.Text = 'Period';
            app.PeriodSlider = uislider(app.UIFigure);
            app.PeriodSlider.Limits = [0 20];
            app.PeriodSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.PeriodSlider.ValueChangedFcn = createCallbackFcn(app, @Period, true);
            app.ReadyButton = uibutton(app.UIFigure, 'state');
            app.ReadyButton.ValueChangedFcn = createCallbackFcn(app, @ReadyButtonValueChanged, true);
            app.ReadyButton.Text = 'Ready! ';
            app.ParticleTrancingLabel = uilabel(app.UIFigure);
            app.ParticleTrancingLabel.Text = 'Particle Trancing';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tracing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end